classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MetaDataTextArea        matlab.ui.control.TextArea
        MetaDataTextAreaLabel   matlab.ui.control.Label
        GRAFICADORDEAUDIOLabel  matlab.ui.control.Label
        PlayButton              matlab.ui.control.Button
        GraficarFrecuencia      matlab.ui.control.Button
        GraficarAmplitud        matlab.ui.control.Button
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarAmplitud
        function GraficarAmplitudPushed(app, event)
            audioinfo("RespiraBien.wav");
            [audio,Fs] = audioread('RespiraBien.wav');%Leer archivos de audio
            sound(audio,Fs);%Reproducir archivos de audio
            audiolength = length(audio);%Obtenga la longitud de datos del archivo de audio
            t = 1:1:audiolength;
            plot(app.UIAxes,t,audio(1:audiolength));
            
        end

        % Button pushed function: GraficarFrecuencia
        function GraficarFrecuenciaButtonPushed(app, event)
            audioinfo("RespiraBien.wav");
            [audio,Fs] = audioread('RespiraBien.wav');%Leer archivos de audio
            sound(audio,Fs);%Reproducir archivos de audio
            audiolength = length(audio);%Obtenga la longitud de datos del archivo de audio
            y =fft(audio);
            y=20*log(abs(y))/log(10);        %Convertir a unidad dBW
            f=[0:(Fs/audiolength):Fs/2];     %Convertir la abscisa a Hz
            y=y(1:length(f));
            plot(app.UIAxes_2,f,y);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            audioinfo("RespiraBien.wav");
            [audio,Fs] = audioread('RespiraBien.wav');
            stop(audio,Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Amplitud')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Frecuencia')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.GraficarAmplitud = uibutton(app.UIFigure, 'push');
            app.GraficarAmplitud.ButtonPushedFcn = createCallbackFcn(app, @GraficarAmplitudPushed, true);
            app.GraficarAmplitud.Text = 'Graficar ';
            app.GraficarFrecuencia = uibutton(app.UIFigure, 'push');
            app.GraficarFrecuencia.ButtonPushedFcn = createCallbackFcn(app, @GraficarFrecuenciaButtonPushed, true);
            app.GraficarFrecuencia.Text = 'Graficar ';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.GRAFICADORDEAUDIOLabel = uilabel(app.UIFigure);
            app.GRAFICADORDEAUDIOLabel.Text = 'GRAFICADOR DE AUDIO';
            app.MetaDataTextAreaLabel = uilabel(app.UIFigure);
            app.MetaDataTextAreaLabel.Text = 'Meta Data';
            app.MetaDataTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end